classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        valorLabel      matlab.ui.control.Label
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
        SwitchLabel     matlab.ui.control.Label
        Switch          matlab.ui.control.Switch
        AnguloenxLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind);%evita problemas al abrir y cerrar el puerto
            serialportlist("available")';
            TivaObj = serial('COM5', 'Baudrate',115200);
            fopen(TivaObj);
            N = 1000;  % Aumentar el número de datos arriba, para apreciar mejor el efecto.
            t = linspace(0,2*pi,N)';    % no es necesario trasponer, es para tener vectores columna
            %y = cos(10*t);
            K = 10;      % Restricción: K debe ser factor de N. Se puede ajustar el código abajo para
                        % eliminar esta restricción. 
         %   app.valorLabel = fscanf(TivaObj, '%f');   
            %figure(7); clf;
            h7 = plot(app.UIAxes,t,zeros(N,1));
            xlim(app.UIAxes,[0,t(end)]);
            buffer = zeros(K,1);
            k = 1;
            %while(app.Switch.Value == "Off")
            while(1)
            for n = 1:N
                data(1,n) = fscanf(TivaObj, '%f');
                app.valorLabel.Text = num2str(data(1,n));
                buffer(k) = data(1,n);
                
                if(k == K)
                    
                    h7.YData((n-K+1):n) = buffer;   % Asume que K es factor de N. De lo contrario,
                                                    % hay que hacer ajustes adicionales.
                    drawnow limitrate
                    k = 1;
                else
                    k = k + 1;    
                end
            end
            
            h7 = plot(app.UIAxes,t,zeros(N,1));
            xlim(app.UIAxes,[0,t(end)]);
            buffer = zeros(K,1);
            end 
            fclose(TivaObj);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           % value = app.EditField.Value;
           % app.valorLabel = value;
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
          %  changingValue = event.Value;
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if (app.Switch.Value == "Off")
                app.EditField.Value = "hola";
            else
                app.EditField.Value = "adios";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inclinación')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'grados')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8];
            app.UIAxes.YTickLabel = {'-90'; '-45'; '0'; '45'; '90'};
            app.UIAxes.Tag = 'grafica1';
            app.valorLabel = uilabel(app.UIFigure);
            app.valorLabel.Text = 'valor';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.AnguloenxLabel = uilabel(app.UIFigure);
            app.AnguloenxLabel.Text = 'Angulo en x:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end